classdef CashierUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Bg                  matlab.ui.container.Panel
        ListTable           matlab.ui.control.Table
        EditField_2Label    matlab.ui.control.Label
        VIPcard             matlab.ui.control.NumericEditField
        VIPButton           matlab.ui.control.Button
        GoodsList           matlab.ui.container.TabGroup
        food                matlab.ui.container.Tab
        Tree                matlab.ui.container.Tree
        meals               matlab.ui.container.TreeNode
        Node_1              matlab.ui.container.TreeNode
        Node_2              matlab.ui.container.TreeNode
        Node_6              matlab.ui.container.TreeNode
        drinks              matlab.ui.container.TreeNode
        Node_27             matlab.ui.container.TreeNode
        Node_26             matlab.ui.container.TreeNode
        Node_3              matlab.ui.container.TreeNode
        Node_7              matlab.ui.container.TreeNode
        Node_8              matlab.ui.container.TreeNode
        snacks              matlab.ui.container.TreeNode
        Node_25             matlab.ui.container.TreeNode
        Node_28             matlab.ui.container.TreeNode
        Add                 matlab.ui.control.Button
        Label               matlab.ui.control.Label
        amount              matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        price1              matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        remaining           matlab.ui.control.NumericEditField
        Daily               matlab.ui.container.Tab
        Tree_2              matlab.ui.container.Tree
        Electronic_devices  matlab.ui.container.TreeNode
        USBNode             matlab.ui.container.TreeNode
        Node_21             matlab.ui.container.TreeNode
        Wash_utensils       matlab.ui.container.TreeNode
        Node_23             matlab.ui.container.TreeNode
        Node_24             matlab.ui.container.TreeNode
        Add2                matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        price2              matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        amount2             matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        remaining2          matlab.ui.control.NumericEditField
        Buttongroup         matlab.ui.container.Panel
        Check               matlab.ui.control.Button
        Chexiao             matlab.ui.control.Button
        Clear               matlab.ui.control.Button
    end

    
    properties (Access = public)
        DatabaseProcessor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Add
        function AddPushed(app, event)
            tempdata = app.ListTable.Data;
            
            if app.amount.Value > app.remaining.Value
                return;
            end
            
            for i=1:length(app.Tree.SelectedNodes)
            if app.Tree.SelectedNodes(i) == app.meals || app.Tree.SelectedNodes(i) == app.drinks || app.Tree.SelectedNodes(i) == app.snacks
                return;
            end
            
            if isempty(tempdata)
                tempdata={app.Tree.SelectedNodes(i).Text,app.amount.Value,app.price1.Value,app.amount.Value*app.price1.Value};
                app.ListTable.Data=tempdata;
                app.amount.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.Tree.SelectedNodes(i).Text));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Tree.SelectedNodes(i).Text,app.amount.Value,app.price1.Value,app.amount.Value*app.price1.Value}];
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                else
                    tempdata{sameind,2}=tempdata{sameind,2}+app.amount.Value;
                    tempdata{sameind,4}=tempdata{sameind,4}+app.amount.Value*app.price1.Value;
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                end
            end
            end
        end

        % Button pushed function: Add2
        function Add2Pushed(app, event)
            tempdata = app.ListTable.Data;
            
            if app.amount2.Value > app.remaining2.Value
                return;
            end
            
            for i=1:length(app.Tree_2.SelectedNodes)
            if app.Tree.SelectedNodes(i) == app.Wash_utensils || app.Tree.SelectedNodes(i) == app.Electronic_devices
            return;
            end
            if isempty(tempdata)
                tempdata={app.Tree_2.SelectedNodes(i).Text,app.amount2.Value,app.price2.Value,app.amount2.Value*app.price2.Value};
                app.ListTable.Data=tempdata;
                app.amount.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.Tree_2.SelectedNodes(i).Text));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Tree_2.SelectedNodes(i).Text,app.amount2.Value,app.price2.Value,app.amount2.Value*app.price2.Value}];
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                else
                    tempdata{sameind,2}=tempdata{sameind,2}+app.amount2.Value;
                    tempdata{sameind,4}=tempdata{sameind,4}+app.amount2.Value*app.price2.Value;
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                end
            end
            end
        end

        % Button pushed function: VIPButton
        function VIPButtonPushed(app, event)
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: Clear
        function ClearPushed(app, event)
            app.ListTable.Data = {};
        end

        % Button pushed function: Check
        function CheckButtonPushed(app, event)
            tempdata = app.ListTable.Data;
            if isempty(tempdata)
                return;
            end
            name = '总计';
            amounts = tempdata{1,2};
            price = tempdata{1,3};
            total = tempdata{1,4};
            a = size(tempdata);
            b = a(1);
            if b == 1
                tempdata = [tempdata;{name,amounts,price,total}];
                app.ListTable.Data=tempdata;
            else
                for i = 2:b
                amounts = amounts + tempdata{i,2};
                total = total + tempdata{i,4};
                end
                price = total/amounts;
                tempdata = [tempdata;{name,amounts,price,total}];
                app.ListTable.Data=tempdata;
            end
            
            app.DatabaseProcessor.displayOrder(app.ListTable.Data);
            app.ListTable.Data = [];
        end

        % Button pushed function: Chexiao
        function ChexiaoButtonPushed(app, event)
            tempdata=app.ListTable.Data;
            deselectdata=tempdata(app.ListTable.UserData,:);
            delind=[];
            for i=1:size(deselectdata,1)
                if deselectdata{i,2}==1
                    delind=[delind;i];
                else
                    tempdata{app.ListTable.UserData(i),2}=tempdata{app.ListTable.UserData(i),2}-1;
                end
            end
            tempdata(app.ListTable.UserData(delind),:)=[];
            app.ListTable.Data=tempdata;
        end

        % Cell selection callback: ListTable
        function ListTableCellSelection(app, event)
            indices = event.Indices;
            app.ListTable.UserData=indices(:,1);
            if isempty(indices)
                app.Chexiao.Enable=0;
            else
                app.Chexiao.Enable=1;
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.DatabaseProcessor.displayMerchandise1(selectedNodes.Text);
            if app.remaining.Value == 0
                app.Add.Enable = 0;
            elseif app.remaining.Value < app.amount.Value
                app.Add.Enable = 0;
            else
                app.Add.Enable = 1;
            end
        end

        % Selection changed function: Tree_2
        function Tree_2SelectionChanged(app, event)
            selectedNodes = app.Tree_2.SelectedNodes;
            app.DatabaseProcessor.displayMerchandise2(selectedNodes.Text);
            if app.remaining2.Value == 0
                app.Add2.Enable = 0;
            elseif app.remaining2.Value < app.amount2.Value
                app.Add2.Enable = 0;
            else
                app.Add2.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Bg = uipanel(app.UIFigure);
            app.Bg.Title = '员工';
            app.ListTable = uitable(app.Bg);
            app.ListTable.ColumnName = {'商品'; '数量'; '单价'; '金额'};
            app.ListTable.RowName = {''};
            app.ListTable.ColumnSortable = false;
            app.ListTable.CellSelectionCallback = createCallbackFcn(app, @ListTableCellSelection, true);
            app.EditField_2Label = uilabel(app.Bg);
            app.EditField_2Label.Text = '会员卡号';
            app.VIPcard = uieditfield(app.Bg, 'numeric');
            app.VIPcard.Limits = [0 999999999];
            app.VIPcard.ValueDisplayFormat = '%.0f';
            app.VIPButton = uibutton(app.Bg, 'push');
            app.VIPButton.ButtonPushedFcn = createCallbackFcn(app, @VIPButtonPushed, true);
            app.VIPButton.Text = '会员卡';
            app.GoodsList = uitabgroup(app.Bg);
            app.food = uitab(app.GoodsList);
            app.food.Title = '食物';
            app.Tree = uitree(app.food);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.meals = uitreenode(app.Tree);
            app.meals.Text = '盒饭';
            app.Node_1 = uitreenode(app.meals);
            app.Node_1.Text = '麻婆豆腐炸鸡饭';
            app.Node_2 = uitreenode(app.meals);
            app.Node_2.Text = '咖喱猪排饭';
            app.Node_6 = uitreenode(app.meals);
            app.Node_6.Text = '奥尔良整只鸡腿饭';
            app.drinks = uitreenode(app.Tree);
            app.drinks.Text = '饮料';
            app.Node_27 = uitreenode(app.drinks);
            app.Node_27.Text = '咖啡';
            app.Node_26 = uitreenode(app.drinks);
            app.Node_26.Text = '牛奶';
            app.Node_3 = uitreenode(app.drinks);
            app.Node_3.Text = '可口可乐';
            app.Node_7 = uitreenode(app.drinks);
            app.Node_7.Text = '百事可乐';
            app.Node_8 = uitreenode(app.drinks);
            app.Node_8.Text = '冰红茶';
            app.snacks = uitreenode(app.Tree);
            app.snacks.Text = '零食';
            app.Node_25 = uitreenode(app.snacks);
            app.Node_25.Text = '巧克力';
            app.Node_28 = uitreenode(app.snacks);
            app.Node_28.Text = '薯片';
            app.Add = uibutton(app.food, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddPushed, true);
            app.Add.Text = {'加入'; ''};
            app.Label = uilabel(app.food);
            app.Label.Text = '数量';
            app.amount = uieditfield(app.food, 'numeric');
            app.amount.Limits = [0 999999999];
            app.amount.ValueDisplayFormat = '%.0f';
            app.amount.Value = 1;
            app.EditField_2Label_2 = uilabel(app.food);
            app.EditField_2Label_2.Text = '单价';
            app.price1 = uieditfield(app.food, 'numeric');
            app.price1.ValueDisplayFormat = '%5.2f';
            app.Label_5 = uilabel(app.food);
            app.Label_5.Text = '库存';
            app.remaining = uieditfield(app.food, 'numeric');
            app.remaining.Limits = [0 999999999];
            app.remaining.ValueDisplayFormat = '%.0f';
            app.Daily = uitab(app.GoodsList);
            app.Daily.Title = '日用品';
            app.Tree_2 = uitree(app.Daily);
            app.Tree_2.SelectionChangedFcn = createCallbackFcn(app, @Tree_2SelectionChanged, true);
            app.Electronic_devices = uitreenode(app.Tree_2);
            app.Electronic_devices.Text = '电子器件';
            app.USBNode = uitreenode(app.Electronic_devices);
            app.USBNode.Text = 'USB线';
            app.Node_21 = uitreenode(app.Electronic_devices);
            app.Node_21.Text = '接线板';
            app.Wash_utensils = uitreenode(app.Tree_2);
            app.Wash_utensils.Text = '个人卫生';
            app.Node_23 = uitreenode(app.Wash_utensils);
            app.Node_23.Text = '牙刷';
            app.Node_24 = uitreenode(app.Wash_utensils);
            app.Node_24.Text = '牙膏';
            app.Add2 = uibutton(app.Daily, 'push');
            app.Add2.ButtonPushedFcn = createCallbackFcn(app, @Add2Pushed, true);
            app.Add2.Text = {'加入'; ''};
            app.Label_3 = uilabel(app.Daily);
            app.Label_3.Text = '单价';
            app.price2 = uieditfield(app.Daily, 'numeric');
            app.Label_4 = uilabel(app.Daily);
            app.Label_4.Text = '数量';
            app.amount2 = uieditfield(app.Daily, 'numeric');
            app.amount2.ValueDisplayFormat = '%.0f';
            app.amount2.Value = 1;
            app.Label_6 = uilabel(app.Daily);
            app.Label_6.Text = '库存';
            app.remaining2 = uieditfield(app.Daily, 'numeric');
            app.remaining2.Limits = [0 999999999];
            app.remaining2.ValueDisplayFormat = '%.0f';
            app.Buttongroup = uipanel(app.Bg);
            app.Buttongroup.Title = '操作';
            app.Check = uibutton(app.Buttongroup, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.Check.Text = '结算';
            app.Chexiao = uibutton(app.Buttongroup, 'push');
            app.Chexiao.ButtonPushedFcn = createCallbackFcn(app, @ChexiaoButtonPushed, true);
            app.Chexiao.Enable = 'off';
            app.Chexiao.Text = '撤销';
            app.Clear = uibutton(app.Buttongroup, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearPushed, true);
            app.Clear.Text = '清空';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CashierUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end