classdef ManagerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Bg                  matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        GoodlistTab         matlab.ui.container.Tab
        GoodTable           matlab.ui.control.Table
        Label               matlab.ui.control.Label
        pricelabel          matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        goodlabel           matlab.ui.control.EditField
        EditField_4Label    matlab.ui.control.Label
        amountlabel         matlab.ui.control.NumericEditField
        Addme               matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        costlabel           matlab.ui.control.NumericEditField
        Abandanoverdue      matlab.ui.control.Button
        Label_6             matlab.ui.control.Label
        Duedata             matlab.ui.control.EditField
        Label_16            matlab.ui.control.Label
        date1               matlab.ui.control.EditField
        Update              matlab.ui.control.Button
        EmployeeTab         matlab.ui.container.Tab
        EmployeeTable       matlab.ui.control.Table
        EmployeeLogin       matlab.ui.control.Button
        EmployeeLogout      matlab.ui.control.Button
        Label_12            matlab.ui.control.Label
        WorknumberField     matlab.ui.control.NumericEditField
        Label_13            matlab.ui.control.Label
        NameField           matlab.ui.control.EditField
        Label_14            matlab.ui.control.Label
        WorkField           matlab.ui.control.EditField
        Label_15            matlab.ui.control.Label
        WorktimeField       matlab.ui.control.EditField
        EditField_2Label    matlab.ui.control.Label
        date2               matlab.ui.control.EditField
        Update2             matlab.ui.control.Button
        Label_19            matlab.ui.control.Label
        PasswordField       matlab.ui.control.EditField
        Tab                 matlab.ui.container.Tab
        Statistic           matlab.ui.control.Table
        Enquiry             matlab.ui.control.Button
        Clear2              matlab.ui.control.Button
        Label_17            matlab.ui.control.Label
        Start               matlab.ui.control.EditField
        Label_18            matlab.ui.control.Label
        End                 matlab.ui.control.EditField
        OperationTab        matlab.ui.container.Tab
        Clear               matlab.ui.control.Button
        OperationListTable  matlab.ui.control.Table
        EditField_3Label    matlab.ui.control.Label
        date3               matlab.ui.control.EditField
        update3             matlab.ui.control.Button
    end

    
    properties (Access = public)
        DatabaseProcessor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.date1.Value = datestr(date,'yyyy/mm/dd');
            app.date2.Value = datestr(date,'yyyy/mm/dd');
            app.date3.Value = datestr(date,'yyyy/mm/dd');
        end

        % Button pushed function: Addme
        function AddmePushed(app, event)
            tempdata = app.GoodTable.Data;
            if isempty(tempdata)
                tempdata={app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value};
                app.GoodTable.Data=tempdata;
                
                op=app.DatabaseProcessor.createOperation(app.Bg.Title,'新进商品',[{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}]);
                me=app.DatabaseProcessor.createMerchandise(app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value);
                app.DatabaseProcessor.displayOperation(op);
                
                app.goodlabel.Value ='';
                app.pricelabel.Value = 0;
                app.costlabel.Value = 0;
                app.amountlabel.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.goodlabel.Value));
                if isempty(sameind)
                    tempdata=[tempdata;{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}];
                    app.GoodTable.Data=tempdata;
                    
                    op=app.DatabaseProcessor.createOperation(app.Bg.Title,'新进商品',[{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}]);
                    me=app.DatabaseProcessor.createMerchandise(app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value);
                    app.DatabaseProcessor.displayOperation(op);
                    
                    app.goodlabel.Value ='';
                    app.pricelabel.Value = 0;
                    app.costlabel.Value = 0;
                    app.amountlabel.Value = 1;
                else
                    if tempdata{sameind,4} == app.Duedata.Value
                       tempdata{sameind,5}=tempdata{sameind,5}+app.amountlabel.Value;
                    else
                        tempdata=[tempdata;{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}];
                    end
                    app.GoodTable.Data=tempdata;
                    
                    op=app.DatabaseProcessor.createOperation(app.Bg.Title,'新进商品',[{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}]);
                    me=app.DatabaseProcessor.createMerchandise(app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value);
                    app.DatabaseProcessor.displayOperation(op);
                    
                    app.goodlabel.Value ='';
                    app.pricelabel.Value = 0;
                    app.costlabel.Value = 0;
                    app.amountlabel.Value = 1;
                end
            end
        end

        % Button pushed function: Abandanoverdue
        function AbandanoverduePushed(app, event)
        tempdata = app.GoodTable.Data;
        if ~isempty(tempdata)
            i = 1;
            while i <= length(tempdata(:,1))
                a = tempdata{i,4};
                b = char(app.date1.Value);
                c = a-b;
                for j = 1:10
                    if c(j) > 0
                        break;
                    elseif c(j) < 0
                        op=app.DatabaseProcessor.createOperation(app.Bg.Title,'抛弃商品',[{tempdata{i,1},tempdata{i,2},tempdata{i,3},tempdata{i,4},tempdata{i,5}}]);
                        me=app.DatabaseProcessor.deleteMerchandise(tempdata{i,1},tempdata{i,2},tempdata{i,3},tempdata{i,4},tempdata{i,5});
                        app.DatabaseProcessor.displayOperation(op);
                        tempdata(i,:) = [];
                        i = i -1;
                        break;
                    end
                end
                i = i + 1;
            end

            app.GoodTable.Data=tempdata;
            app.goodlabel.Value ='';
            app.pricelabel.Value = 0;
            app.costlabel.Value = 0;
            app.amountlabel.Value = 1;
        end
        end

        % Button pushed function: EmployeeLogin
        function EmployeeLoginPushed(app, event)
            tempdata = app.EmployeeTable.Data;
            if isempty(tempdata)
                tempdata={app.WorknumberField.Value,app.NameField.Value,app.WorkField.Value,app.WorktimeField.Value};
                app.EmployeeTable.Data=tempdata;
                
                op=app.DatabaseProcessor.createOperation(app.Bg.Title,'新进干员',[{app.NameField.Value,app.WorknumberField.Value,app.WorkField.Value,app.WorktimeField.Value}]);
                em=app.DatabaseProcessor.createEmployee(app.NameField.Value,app.WorknumberField.Value,app.PasswordField.Value,app.WorkField.Value,app.WorktimeField.Value);
                app.DatabaseProcessor.displayOperation(op);
                
                app.NameField.Value ='';
                app.WorknumberField.Value = 0;
                app.WorktimeField.Value = '';
            else
                tempdata=[tempdata;{app.WorknumberField.Value,app.NameField.Value,app.WorkField.Value,app.WorktimeField.Value}];
                app.EmployeeTable.Data=tempdata;
                
                op=app.DatabaseProcessor.createOperation(app.Bg.Title,'新进干员',[{app.NameField.Value,app.WorknumberField.Value,app.WorkField.Value,app.WorktimeField.Value}]);
                em=app.DatabaseProcessor.createEmployee(app.NameField.Value,app.WorknumberField.Value,app.PasswordField.Value,app.WorkField.Value,app.WorktimeField.Value);
                app.DatabaseProcessor.displayOperation(op);
                
                app.NameField.Value ='';
                app.WorknumberField.Value = 0;
                app.WorktimeField.Value = '';
            end
        end

        % Button pushed function: EmployeeLogout
        function EmployeeLogoutPushed(app, event)
            tempdata=app.EmployeeTable.Data;
            deselectdata=tempdata(app.EmployeeTable.UserData,:);
            delind=[];
            i = size(deselectdata,1);
            delind=[delind;i];
            tempdata(app.EmployeeTable.UserData(delind),:)=[];
            app.EmployeeTable.Data=tempdata;
            
            op=app.DatabaseProcessor.createOperation(app.Bg.Title,'辞退干员',[{deselectdata{1,2},deselectdata{1,1},deselectdata{1,3},deselectdata{1,4}}]);
            em=app.DatabaseProcessor.deleteEmployee(deselectdata{1,2},deselectdata{1,1},deselectdata{1,3},deselectdata{1,4});    
            app.DatabaseProcessor.displayOperation(op);
        end

        % Cell selection callback: EmployeeTable
        function EmployeeTableCellSelection(app, event)
            indices = event.Indices;
            app.EmployeeTable.UserData=indices(:,1);
            if isempty(indices)
                app.EmployeeLogout.Enable=0;
            else
                app.EmployeeLogout.Enable=1;
            end
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            app.DatabaseProcessor.updateMerchandise();
        end

        % Button pushed function: Update2
        function Update2Pushed(app, event)
            app.DatabaseProcessor.updateEmployee();
        end

        % Button pushed function: Clear2
        function Clear2ButtonPushed(app, event)
            app.Statistic.Data = [];
        end

        % Button pushed function: Enquiry
        function EnquiryButtonPushed(app, event)
            start = app.Start.Value;
            due = app.End.Value;
            if isempty(start) || isempty(due)
                return;
            end
            app.DatabaseProcessor.enquiry(start,due);
        end

        % Button pushed function: update3
        function update3ButtonPushed(app, event)
            app.DatabaseProcessor.updateOperation();
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.OperationListTable.Data = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Bg = uipanel(app.UIFigure);
            app.Bg.Title = '经理';
            app.TabGroup = uitabgroup(app.Bg);
            app.GoodlistTab = uitab(app.TabGroup);
            app.GoodlistTab.Title = '商品';
            app.GoodTable = uitable(app.GoodlistTab);
            app.GoodTable.ColumnName = {'商品'; '价格'; '成本'; '保质期'; '库存'};
            app.GoodTable.RowName = {};
            app.Label = uilabel(app.GoodlistTab);
            app.Label.Text = '价格';
            app.pricelabel = uieditfield(app.GoodlistTab, 'numeric');
            app.pricelabel.ValueDisplayFormat = '%5.2f';
            app.Label_4 = uilabel(app.GoodlistTab);
            app.Label_4.Text = '商品';
            app.goodlabel = uieditfield(app.GoodlistTab, 'text');
            app.EditField_4Label = uilabel(app.GoodlistTab);
            app.EditField_4Label.Text = '数量';
            app.amountlabel = uieditfield(app.GoodlistTab, 'numeric');
            app.amountlabel.Limits = [0 999999999];
            app.amountlabel.ValueDisplayFormat = '%.0f';
            app.amountlabel.Value = 1;
            app.Addme = uibutton(app.GoodlistTab, 'push');
            app.Addme.ButtonPushedFcn = createCallbackFcn(app, @AddmePushed, true);
            app.Addme.Text = '添加商品';
            app.Label_3 = uilabel(app.GoodlistTab);
            app.Label_3.Text = '成本';
            app.costlabel = uieditfield(app.GoodlistTab, 'numeric');
            app.costlabel.ValueDisplayFormat = '%5.2f';
            app.Abandanoverdue = uibutton(app.GoodlistTab, 'push');
            app.Abandanoverdue.ButtonPushedFcn = createCallbackFcn(app, @AbandanoverduePushed, true);
            app.Abandanoverdue.Text = '抛弃过期商品';
            app.Label_6 = uilabel(app.GoodlistTab);
            app.Label_6.Text = '保质期';
            app.Duedata = uieditfield(app.GoodlistTab, 'text');
            app.Label_16 = uilabel(app.GoodlistTab);
            app.Label_16.Text = '日期';
            app.date1 = uieditfield(app.GoodlistTab, 'text');
            app.Update = uibutton(app.GoodlistTab, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Text = '更新';
            app.EmployeeTab = uitab(app.TabGroup);
            app.EmployeeTab.Title = '雇员';
            app.EmployeeTable = uitable(app.EmployeeTab);
            app.EmployeeTable.ColumnName = {'工号'; '姓名'; '职位'; '工作时间'};
            app.EmployeeTable.RowName = {};
            app.EmployeeTable.CellSelectionCallback = createCallbackFcn(app, @EmployeeTableCellSelection, true);
            app.EmployeeLogin = uibutton(app.EmployeeTab, 'push');
            app.EmployeeLogin.ButtonPushedFcn = createCallbackFcn(app, @EmployeeLoginPushed, true);
            app.EmployeeLogin.Text = '加入';
            app.EmployeeLogout = uibutton(app.EmployeeTab, 'push');
            app.EmployeeLogout.ButtonPushedFcn = createCallbackFcn(app, @EmployeeLogoutPushed, true);
            app.EmployeeLogout.Enable = 'off';
            app.EmployeeLogout.Text = '辞退';
            app.Label_12 = uilabel(app.EmployeeTab);
            app.Label_12.Text = '工号';
            app.WorknumberField = uieditfield(app.EmployeeTab, 'numeric');
            app.WorknumberField.Limits = [0 999999999];
            app.WorknumberField.ValueDisplayFormat = '%.0f';
            app.Label_13 = uilabel(app.EmployeeTab);
            app.Label_13.Text = '姓名';
            app.NameField = uieditfield(app.EmployeeTab, 'text');
            app.Label_14 = uilabel(app.EmployeeTab);
            app.Label_14.Text = '职位';
            app.WorkField = uieditfield(app.EmployeeTab, 'text');
            app.Label_15 = uilabel(app.EmployeeTab);
            app.Label_15.Text = '工作时间';
            app.WorktimeField = uieditfield(app.EmployeeTab, 'text');
            app.EditField_2Label = uilabel(app.EmployeeTab);
            app.EditField_2Label.Text = '日期';
            app.date2 = uieditfield(app.EmployeeTab, 'text');
            app.Update2 = uibutton(app.EmployeeTab, 'push');
            app.Update2.ButtonPushedFcn = createCallbackFcn(app, @Update2Pushed, true);
            app.Update2.Text = '更新';
            app.Label_19 = uilabel(app.EmployeeTab);
            app.Label_19.Text = '密码';
            app.PasswordField = uieditfield(app.EmployeeTab, 'text');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '结算';
            app.Statistic = uitable(app.Tab);
            app.Statistic.ColumnName = {'交易'; '时间'; '工号'; '金额'};
            app.Statistic.RowName = {};
            app.Enquiry = uibutton(app.Tab, 'push');
            app.Enquiry.ButtonPushedFcn = createCallbackFcn(app, @EnquiryButtonPushed, true);
            app.Enquiry.Text = '查询';
            app.Clear2 = uibutton(app.Tab, 'push');
            app.Clear2.ButtonPushedFcn = createCallbackFcn(app, @Clear2ButtonPushed, true);
            app.Clear2.Text = '清空';
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = '开始时间';
            app.Start = uieditfield(app.Tab, 'text');
            app.Label_18 = uilabel(app.Tab);
            app.Label_18.Text = '截止时间';
            app.End = uieditfield(app.Tab, 'text');
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = '历史';
            app.Clear = uibutton(app.OperationTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = '清空';
            app.OperationListTable = uitable(app.OperationTab);
            app.OperationListTable.ColumnName = {'工号'; '姓名'; '时间'; '操作'; '对象'};
            app.OperationListTable.RowName = {};
            app.EditField_3Label = uilabel(app.OperationTab);
            app.EditField_3Label.Text = '日期';
            app.date3 = uieditfield(app.OperationTab, 'text');
            app.update3 = uibutton(app.OperationTab, 'push');
            app.update3.ButtonPushedFcn = createCallbackFcn(app, @update3ButtonPushed, true);
            app.update3.Text = '更新';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManagerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end