classdef cashier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        ListTable           matlab.ui.control.Table
        EditField_2Label    matlab.ui.control.Label
        VIPcard             matlab.ui.control.NumericEditField
        VIPButton           matlab.ui.control.Button
        GoodsList           matlab.ui.container.TabGroup
        food                matlab.ui.container.Tab
        Tree                matlab.ui.container.Tree
        meals               matlab.ui.container.TreeNode
        Node_1              matlab.ui.container.TreeNode
        Node_2              matlab.ui.container.TreeNode
        Node_6              matlab.ui.container.TreeNode
        Node_9              matlab.ui.container.TreeNode
        Node_13             matlab.ui.container.TreeNode
        Node_14             matlab.ui.container.TreeNode
        drinks              matlab.ui.container.TreeNode
        Node_3              matlab.ui.container.TreeNode
        Node_7              matlab.ui.container.TreeNode
        Node_8              matlab.ui.container.TreeNode
        Node_12             matlab.ui.container.TreeNode
        Node_15             matlab.ui.container.TreeNode
        Node_16             matlab.ui.container.TreeNode
        Node_17             matlab.ui.container.TreeNode
        snacks              matlab.ui.container.TreeNode
        Node_11             matlab.ui.container.TreeNode
        Node_20             matlab.ui.container.TreeNode
        Node_18             matlab.ui.container.TreeNode
        Node_19             matlab.ui.container.TreeNode
        EnterButton         matlab.ui.control.Button
        Label               matlab.ui.control.Label
        amount              matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        Daily               matlab.ui.container.Tab
        Tree_2              matlab.ui.container.Tree
        Electronic_devices  matlab.ui.container.TreeNode
        USBNode             matlab.ui.container.TreeNode
        Node_21             matlab.ui.container.TreeNode
        Wash_utensils       matlab.ui.container.TreeNode
        Node_23             matlab.ui.container.TreeNode
        Node_24             matlab.ui.container.TreeNode
        EnterButton_2       matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        amount_2            matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        EditField           matlab.ui.control.NumericEditField
        Buttongroup         matlab.ui.container.Panel
        Check               matlab.ui.control.Button
        Chexiao             matlab.ui.control.Button
        Clear               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            tempdata = app.ListTable.Data;
            for i=1:length(app.Tree.SelectedNodes)
            if isempty(tempdata)
                tempdata={app.Tree.SelectedNodes(i).Text,app.amount.Value,app.Tree.SelectedNodes(i).NodeData,app.amount.Value*app.Tree.SelectedNodes(i).NodeData};
                app.ListTable.Data=tempdata;
                app.amount.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.Tree.SelectedNodes(i).Text));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Tree.SelectedNodes(i).Text,app.amount.Value,app.Tree.SelectedNodes(i).NodeData,app.amount.Value*app.Tree.SelectedNodes(i).NodeData}];
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                else
                    tempdata{sameind,2}=tempdata{sameind,2}+app.amount.Value;
                    tempdata{sameind,4}=tempdata{sameind,4}+app.amount.Value*app.Tree.SelectedNodes(i).NodeData;
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                end
            end
            end
        end

        % Button pushed function: EnterButton_2
        function EnterButton_2Pushed(app, event)
            tempdata = app.ListTable.Data;
            for i=1:length(app.Tree_2.SelectedNodes)
            if isempty(tempdata)
                tempdata={app.Tree_2.SelectedNodes(i).Text,app.amount.Value,app.Tree_2.SelectedNodes(i).NodeData,app.amount.Value*app.Tree_2.SelectedNodes(i).NodeData};
                app.ListTable.Data=tempdata;
                app.amount.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.Tree_2.SelectedNodes(i).Text));
                if isempty(sameind)
                    tempdata=[tempdata;{app.Tree_2.SelectedNodes(i).Text,app.amount.Value,app.Tree_2.SelectedNodes(i).NodeData,app.amount.Value*app.Tree_2.SelectedNodes(i).NodeData}];
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                else
                    tempdata{sameind,2}=tempdata{sameind,2}+app.amount.Value;
                    tempdata{sameind,4}=tempdata{sameind,4}+app.amount.Value*app.Tree_2.SelectedNodes(i).NodeData;
                    app.ListTable.Data=tempdata;
                    app.amount.Value = 1;
                end
            end
            end
        end

        % Button pushed function: VIPButton
        function VIPButtonPushed(app, event)
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: Clear
        function ClearPushed(app, event)
            app.ListTable.Data = {};
        end

        % Button pushed function: Check
        function CheckButtonPushed(app, event)
            tempdata = app.ListTable.Data;
            name = '总计';
            amounts = tempdata{1,2};
            price = tempdata{1,3};
            total = tempdata{1,4};
            a = size(tempdata);
            b = a(1);
            if b == 1
                tempdata = [tempdata;{name,amounts,price,total}];
                app.ListTable.Data=tempdata;
            else
                for i = 2:b
                amounts = amounts + tempdata{i,2};
                total = total + tempdata{i,4};
                end
                price = total/amounts;
                tempdata = [tempdata;{name,amounts,price,total}];
                app.ListTable.Data=tempdata;
            end
        end

        % Button pushed function: Chexiao
        function ChexiaoButtonPushed(app, event)
            tempdata=app.ListTable.Data;
            deselectdata=tempdata(app.ListTable.UserData,:);
            delind=[];
            for i=1:size(deselectdata,1)
                if deselectdata{i,2}==1
                    delind=[delind;i];
                else
                    tempdata{app.ListTable.UserData(i),2}=tempdata{app.ListTable.UserData(i),2}-1;
                end
            end
            tempdata(app.ListTable.UserData(delind),:)=[];
            app.ListTable.Data=tempdata;
        end

        % Cell selection callback: ListTable
        function ListTableCellSelection(app, event)
            indices = event.Indices;
            app.ListTable.UserData=indices(:,1);
            if isempty(indices)
                app.Chexiao.Enable=0;
            else
                app.Chexiao.Enable=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '结算';
            app.ListTable = uitable(app.Panel);
            app.ListTable.ColumnName = {'商品'; '数量'; '单价'; '金额'};
            app.ListTable.RowName = {''};
            app.ListTable.CellSelectionCallback = createCallbackFcn(app, @ListTableCellSelection, true);
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '会员卡号';
            app.VIPcard = uieditfield(app.Panel, 'numeric');
            app.VIPcard.ValueDisplayFormat = '%.0f';
            app.VIPButton = uibutton(app.Panel, 'push');
            app.VIPButton.ButtonPushedFcn = createCallbackFcn(app, @VIPButtonPushed, true);
            app.VIPButton.Text = '会员卡';
            app.GoodsList = uitabgroup(app.Panel);
            app.food = uitab(app.GoodsList);
            app.food.Title = '食物';
            app.Tree = uitree(app.food);
            app.meals = uitreenode(app.Tree);
            app.meals.Text = '盒饭';
            app.Node_1 = uitreenode(app.meals);
            app.Node_1.NodeData = 11.5;
            app.Node_1.Text = '麻婆豆腐炸鸡饭';
            app.Node_2 = uitreenode(app.meals);
            app.Node_2.NodeData = 11.5;
            app.Node_2.Text = '咖喱猪排饭';
            app.Node_6 = uitreenode(app.meals);
            app.Node_6.NodeData = 15.8;
            app.Node_6.Text = '奥尔良整只鸡腿饭';
            app.Node_9 = uitreenode(app.meals);
            app.Node_9.Text = '双拼饭';
            app.Node_13 = uitreenode(app.Node_9);
            app.Node_13.NodeData = 10;
            app.Node_13.Text = '双拼饭1';
            app.Node_14 = uitreenode(app.Node_9);
            app.Node_14.NodeData = 10;
            app.Node_14.Text = '双拼饭2';
            app.drinks = uitreenode(app.Tree);
            app.drinks.Text = '饮料';
            app.Node_3 = uitreenode(app.drinks);
            app.Node_3.NodeData = 4;
            app.Node_3.Text = '可口可乐';
            app.Node_7 = uitreenode(app.drinks);
            app.Node_7.NodeData = 4;
            app.Node_7.Text = '百事可乐';
            app.Node_8 = uitreenode(app.drinks);
            app.Node_8.NodeData = 4;
            app.Node_8.Text = '冰红茶';
            app.Node_12 = uitreenode(app.drinks);
            app.Node_12.Text = '牛奶';
            app.Node_15 = uitreenode(app.Node_12);
            app.Node_15.NodeData = 4;
            app.Node_15.Text = '莫斯利安';
            app.Node_16 = uitreenode(app.drinks);
            app.Node_16.Text = '咖啡';
            app.Node_17 = uitreenode(app.Node_16);
            app.Node_17.NodeData = 4;
            app.Node_17.Text = '咖啡1';
            app.snacks = uitreenode(app.Tree);
            app.snacks.Text = '零食';
            app.Node_11 = uitreenode(app.snacks);
            app.Node_11.Text = '巧克力';
            app.Node_20 = uitreenode(app.Node_11);
            app.Node_20.NodeData = 5;
            app.Node_20.Text = '士力架';
            app.Node_18 = uitreenode(app.snacks);
            app.Node_18.Text = '薯片';
            app.Node_19 = uitreenode(app.Node_18);
            app.Node_19.NodeData = 7;
            app.Node_19.Text = '乐事';
            app.EnterButton = uibutton(app.food, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = {'加入'; ''};
            app.Label = uilabel(app.food);
            app.Label.Text = '数量';
            app.amount = uieditfield(app.food, 'numeric');
            app.amount.ValueDisplayFormat = '%.0f';
            app.amount.Value = 1;
            app.EditField_2Label_2 = uilabel(app.food);
            app.EditField_2Label_2.Text = '单价';
            app.EditField_3 = uieditfield(app.food, 'numeric');
            app.Daily = uitab(app.GoodsList);
            app.Daily.Title = '日用品';
            app.Tree_2 = uitree(app.Daily);
            app.Electronic_devices = uitreenode(app.Tree_2);
            app.Electronic_devices.Text = '电子器件';
            app.USBNode = uitreenode(app.Electronic_devices);
            app.USBNode.NodeData = 5;
            app.USBNode.Text = 'USB线';
            app.Node_21 = uitreenode(app.Electronic_devices);
            app.Node_21.NodeData = 20;
            app.Node_21.Text = '接线板';
            app.Wash_utensils = uitreenode(app.Tree_2);
            app.Wash_utensils.Text = '个人卫生';
            app.Node_23 = uitreenode(app.Wash_utensils);
            app.Node_23.NodeData = 5;
            app.Node_23.Text = '牙刷';
            app.Node_24 = uitreenode(app.Wash_utensils);
            app.Node_24.NodeData = 10;
            app.Node_24.Text = '牙膏';
            app.EnterButton_2 = uibutton(app.Daily, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_2Pushed, true);
            app.EnterButton_2.Text = {'加入'; ''};
            app.Label_2 = uilabel(app.Daily);
            app.Label_2.Text = '数量';
            app.amount_2 = uieditfield(app.Daily, 'numeric');
            app.amount_2.ValueDisplayFormat = '%.0f';
            app.amount_2.Value = 1;
            app.Label_3 = uilabel(app.Daily);
            app.Label_3.Text = '单价';
            app.EditField = uieditfield(app.Daily, 'numeric');
            app.Buttongroup = uipanel(app.Panel);
            app.Buttongroup.Title = '操作';
            app.Check = uibutton(app.Buttongroup, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.Check.Text = '结算';
            app.Chexiao = uibutton(app.Buttongroup, 'push');
            app.Chexiao.ButtonPushedFcn = createCallbackFcn(app, @ChexiaoButtonPushed, true);
            app.Chexiao.Enable = 'off';
            app.Chexiao.Text = '撤销';
            app.Clear = uibutton(app.Buttongroup, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearPushed, true);
            app.Clear.Text = '清空';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cashier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end