classdef manager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        GoodlistTab       matlab.ui.container.Tab
        GoodTable         matlab.ui.control.Table
        Label             matlab.ui.control.Label
        pricelabel        matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        goodlabel         matlab.ui.control.EditField
        EditField_4Label  matlab.ui.control.Label
        amountlabel       matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        Label_3           matlab.ui.control.Label
        costlabel         matlab.ui.control.NumericEditField
        Abandanoverdue    matlab.ui.control.Button
        Label_5           matlab.ui.control.Label
        Date1             matlab.ui.control.DatePicker
        Label_6           matlab.ui.control.Label
        Duedata           matlab.ui.control.EditField
        EmployeeTab       matlab.ui.container.Tab
        EmployeeTable     matlab.ui.control.Table
        EmployeeLogin     matlab.ui.control.Button
        EmployeeLogout    matlab.ui.control.Button
        Label_10          matlab.ui.control.Label
        Date1_2           matlab.ui.control.DatePicker
        Label_12          matlab.ui.control.Label
        WorknumberField   matlab.ui.control.NumericEditField
        Label_13          matlab.ui.control.Label
        NameField         matlab.ui.control.EditField
        Label_14          matlab.ui.control.Label
        WorkField         matlab.ui.control.EditField
        Label_15          matlab.ui.control.Label
        WorktimeField     matlab.ui.control.EditField
        OperationTab      matlab.ui.container.Tab
        UITable4          matlab.ui.control.Table
        Button_6          matlab.ui.control.Button
        Label_11          matlab.ui.control.Label
        Date1_3           matlab.ui.control.DatePicker
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            tempdata = app.GoodTable.Data;
            if isempty(tempdata)
                tempdata={app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value};
                app.GoodTable.Data=tempdata;
                app.goodlabel.Value ='';
                app.pricelabel.Value = 0;
                app.costlabel.Value = 0;
                app.amountlabel.Value = 1;
            else
                sameind=find(contains(tempdata(:,1),app.goodlabel.Value));
                if isempty(sameind)
                    tempdata=[tempdata;{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}];
                    app.GoodTable.Data=tempdata;
                    app.goodlabel.Value ='';
                    app.pricelabel.Value = 0;
                    app.costlabel.Value = 0;
                    app.amountlabel.Value = 1;
                else
                    if tempdata{sameind,4} == app.Duedata.Value
                       tempdata{sameind,5}=tempdata{sameind,5}+app.amountlabel.Value;
                    else
                        tempdata=[tempdata;{app.goodlabel.Value,app.pricelabel.Value,app.costlabel.Value,app.Duedata.Value,app.amountlabel.Value}];
                    end
                    app.GoodTable.Data=tempdata;
                    app.goodlabel.Value ='';
                    app.pricelabel.Value = 0;
                    app.costlabel.Value = 0;
                    app.amountlabel.Value = 1;
                end
            end
        end

        % Button pushed function: Abandanoverdue
        function AbandanoverduePushed(app, event)
        tempdata = app.GoodTable.Data;
        if ~isempty(tempdata)
            for i = 1:length(tempdata(:,1))
                a = tempdata{i,4};
                b = char(app.Date1.Value);
                c = a-b;
                for j = 1:10
                    if c(j) < 0
                        tempdata{i,4} = 0;
                        app.GoodTable.Data=tempdata;
                        app.goodlabel.Value ='';
                        app.pricelabel.Value = 0;
                        app.costlabel.Value = 0;
                        app.amountlabel.Value = 1;
                        break;
                    end
                end
            end
        end
        end

        % Button pushed function: EmployeeLogin
        function EmployeeLoginPushed(app, event)
            tempdata = app.EmployeeTable.Data;
            if isempty(tempdata)
                tempdata={app.WorknumberField.Value,app.NameField.Value,app.WorkField.Value,app.WorktimeField.Value};
                app.EmployeeTable.Data=tempdata;
                app.NameField.Value ='';
                app.WorknumberField.Value = 0;
                app.WorktimeField.Value = '';
            else
                tempdata=[tempdata;{app.WorknumberField.Value,app.NameField.Value,app.WorkField.Value,app.WorktimeField.Value}];
                app.EmployeeTable.Data=tempdata;
                app.NameField.Value ='';
                app.WorknumberField.Value = 0;
                app.WorktimeField.Value = '';
            end
        end

        % Button pushed function: EmployeeLogout
        function EmployeeLogoutPushed(app, event)
            tempdata=app.EmployeeTable.Data;
            deselectdata=tempdata(app.EmployeeTable.UserData,:);
            delind=[];
            for i=1:size(deselectdata,1)
                delind=[delind;i];
            end
            tempdata(app.EmployeeTable.UserData(delind),:)=[];
            app.EmployeeTable.Data=tempdata;
        end

        % Cell selection callback: EmployeeTable
        function EmployeeTableCellSelection(app, event)
            indices = event.Indices;
            app.EmployeeTable.UserData=indices(:,1);
            if isempty(indices)
                app.EmployeeLogout.Enable=0;
            else
                app.EmployeeLogout.Enable=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GoodlistTab = uitab(app.TabGroup);
            app.GoodlistTab.Title = '商品';
            app.GoodTable = uitable(app.GoodlistTab);
            app.GoodTable.ColumnName = {'商品'; '价格'; '成本'; '保质期'; '库存'};
            app.GoodTable.RowName = {};
            app.Label = uilabel(app.GoodlistTab);
            app.Label.Text = '价格';
            app.pricelabel = uieditfield(app.GoodlistTab, 'numeric');
            app.Label_4 = uilabel(app.GoodlistTab);
            app.Label_4.Text = '商品';
            app.goodlabel = uieditfield(app.GoodlistTab, 'text');
            app.EditField_4Label = uilabel(app.GoodlistTab);
            app.EditField_4Label.Text = '数量';
            app.amountlabel = uieditfield(app.GoodlistTab, 'numeric');
            app.amountlabel.Value = 1;
            app.Button = uibutton(app.GoodlistTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '添加商品';
            app.Label_3 = uilabel(app.GoodlistTab);
            app.Label_3.Text = '成本';
            app.costlabel = uieditfield(app.GoodlistTab, 'numeric');
            app.Abandanoverdue = uibutton(app.GoodlistTab, 'push');
            app.Abandanoverdue.ButtonPushedFcn = createCallbackFcn(app, @AbandanoverduePushed, true);
            app.Abandanoverdue.Text = '抛弃过期商品';
            app.Label_5 = uilabel(app.GoodlistTab);
            app.Label_5.Text = '日期';
            app.Date1 = uidatepicker(app.GoodlistTab);
            app.Label_6 = uilabel(app.GoodlistTab);
            app.Label_6.Text = '保质期';
            app.Duedata = uieditfield(app.GoodlistTab, 'text');
            app.EmployeeTab = uitab(app.TabGroup);
            app.EmployeeTab.Title = '雇员';
            app.EmployeeTable = uitable(app.EmployeeTab);
            app.EmployeeTable.ColumnName = {'工号'; '姓名'; '职位'; '排班时间'};
            app.EmployeeTable.RowName = {};
            app.EmployeeTable.CellSelectionCallback = createCallbackFcn(app, @EmployeeTableCellSelection, true);
            app.EmployeeLogin = uibutton(app.EmployeeTab, 'push');
            app.EmployeeLogin.ButtonPushedFcn = createCallbackFcn(app, @EmployeeLoginPushed, true);
            app.EmployeeLogin.Text = '加入';
            app.EmployeeLogout = uibutton(app.EmployeeTab, 'push');
            app.EmployeeLogout.ButtonPushedFcn = createCallbackFcn(app, @EmployeeLogoutPushed, true);
            app.EmployeeLogout.Enable = 'off';
            app.EmployeeLogout.Text = '删除';
            app.Label_10 = uilabel(app.EmployeeTab);
            app.Label_10.Text = '日期';
            app.Date1_2 = uidatepicker(app.EmployeeTab);
            app.Label_12 = uilabel(app.EmployeeTab);
            app.Label_12.Text = '工号';
            app.WorknumberField = uieditfield(app.EmployeeTab, 'numeric');
            app.Label_13 = uilabel(app.EmployeeTab);
            app.Label_13.Text = '姓名';
            app.NameField = uieditfield(app.EmployeeTab, 'text');
            app.Label_14 = uilabel(app.EmployeeTab);
            app.Label_14.Text = '职位';
            app.WorkField = uieditfield(app.EmployeeTab, 'text');
            app.Label_15 = uilabel(app.EmployeeTab);
            app.Label_15.Text = '工作时间';
            app.WorktimeField = uieditfield(app.EmployeeTab, 'text');
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = '操作';
            app.UITable4 = uitable(app.OperationTab);
            app.UITable4.ColumnName = {'工作号'; '姓名'; '操作'; '时间'};
            app.UITable4.RowName = {};
            app.Button_6 = uibutton(app.OperationTab, 'push');
            app.Button_6.Text = '清空';
            app.Label_11 = uilabel(app.OperationTab);
            app.Label_11.Text = '日期';
            app.Date1_3 = uidatepicker(app.OperationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end